classdef GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SliderLabel           matlab.ui.control.Label
        Slider                matlab.ui.control.Slider
        Slider_2Label         matlab.ui.control.Label
        Slider_2              matlab.ui.control.Slider
        ChoosefolderButton    matlab.ui.control.Button
        LengthEditFieldLabel  matlab.ui.control.Label
        LengthEditField       matlab.ui.control.NumericEditField
        AxesFullData          matlab.ui.control.UIAxes
        AxesChosenData        matlab.ui.control.UIAxes
        AxesVoltages          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filenamesToRead % Description
        sFolderPath % Description
        val % Description
        times % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear
            clc
            %app.filenamesToRead = ["_TEL_HVBMS_CURR.txt", "_TEL_HVBMS_MAXCVOLT.txt", "_TEL_HVBMS_MINCVOLT.txt"]
            %app.filenamesToRead
        end

        % Button pushed function: ChoosefolderButton
        function ChoosefolderButtonPushed(app, event)
            app.sFolderPath='/home/jacek/Dokumenty/Matlab/Testy LEM MotoPark/lem_logi_motopark_30102020/20201030_092123662678_decoded'; %for quick testing only
            if(exist(app.sFolderPath))
                app.sFolderPath=uigetdir(app.sFolderPath);
            else
                app.sFolderPath=uigetdir();
            end
            %sFolderPath=uigetdir()
            app.ChoosefolderButton.BackgroundColor=[1, 0, 0]; %change to red until it hasn't finished reading files
            app.filenamesToRead
            app.filenamesToRead = ["_TEL_HVBMS_CURR.txt", "_TEL_HVBMS_MAXCVOLT.txt", "_TEL_HVBMS_MINCVOLT.txt"]
            [oTimestamps, times, val] = readSensorsInFolder(app.filenamesToRead, app.sFolderPath);
            app.ChoosefolderButton.BackgroundColor=[1, 1, 1];
            app.val=val;
            app.times=times;
            plot(app.AxesFullData, times, val(:,1))
            app.Slider.Limits = [1,length(oTimestamps)]
            app.Slider_2.Limits = [1,length(oTimestamps)]
            app.Slider_2.Value = length(oTimestamps)
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            plot(app.AxesChosenData, app.times(app.Slider.Value:app.Slider_2.Value), app.val(app.Slider.Value:app.Slider_2.Value,1))
            plot(app.AxesVoltages, app.times(app.Slider.Value:app.Slider_2.Value), app.val(app.Slider.Value:app.Slider_2.Value,2:3))
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            plot(app.AxesChosenData, app.times(app.Slider.Value:app.Slider_2.Value), app.val(app.Slider.Value:app.Slider_2.Value,1))
            plot(app.AxesVoltages, app.times(app.Slider.Value:app.Slider_2.Value), app.val(app.Slider.Value:app.Slider_2.Value,2:3))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = '';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.ChoosefolderButton = uibutton(app.UIFigure, 'push');
            app.ChoosefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderButtonPushed, true);
            app.ChoosefolderButton.Text = 'Choose folder';
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditField.Editable = 'off';
            app.AxesFullData = uiaxes(app.UIFigure);
            title(app.AxesFullData, 'Current')
            xlabel(app.AxesFullData, 'X')
            ylabel(app.AxesFullData, 'Y')
            zlabel(app.AxesFullData, 'Z')
            app.AxesChosenData = uiaxes(app.UIFigure);
            title(app.AxesChosenData, 'Total Current')
            xlabel(app.AxesChosenData, 'X')
            ylabel(app.AxesChosenData, 'Y')
            zlabel(app.AxesChosenData, 'Z')
            app.AxesVoltages = uiaxes(app.UIFigure);
            title(app.AxesVoltages, 'Voltages')
            xlabel(app.AxesVoltages, 'X')
            ylabel(app.AxesVoltages, 'Y')
            zlabel(app.AxesVoltages, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end